classdef Meta_Terminal_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetaTerminal                    matlab.ui.Figure
        AngleAxes                       matlab.ui.control.UIAxes
        VelocityAxes                    matlab.ui.control.UIAxes
        CurrentAxes                     matlab.ui.control.UIAxes
        ConnectButton                   matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        SendText                        matlab.ui.control.EditField
        SendButton                      matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        FunctionButtonGroup             matlab.ui.container.ButtonGroup
        statsButton                     matlab.ui.control.Button
        threadsButton                   matlab.ui.control.Button
        memButton                       matlab.ui.control.Button
        systimeButton                   matlab.ui.control.Button
        EnableYawCheckBox               matlab.ui.control.CheckBox
        EnablePitchCheckBox             matlab.ui.control.CheckBox
        g_enableButton                  matlab.ui.control.Button
        g_fixButton                     matlab.ui.control.Button
        YawTargetCurrentEditFieldLabel  matlab.ui.control.Label
        YawTargetCurrentEditField       matlab.ui.control.EditField
        PitchTargetCurrentEditFieldLabel  matlab.ui.control.Label
        PitchTargetCurrentEditField     matlab.ui.control.EditField
        g_setButton                     matlab.ui.control.Button
        ClearDataButton                 matlab.ui.control.Button
        AngleLabel                      matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        TargetLabel                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CurrentLabel_2                  matlab.ui.control.Label
        TargetLabel_2                   matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        VelocityLabel                   matlab.ui.control.Label
        CurrentLabel_3                  matlab.ui.control.Label
        TargetLabel_3                   matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        CurrentLabel_4                  matlab.ui.control.Label
        DisplayButtonGroup              matlab.ui.container.ButtonGroup
        YawButton                       matlab.ui.control.RadioButton
        PitchButton                     matlab.ui.control.RadioButton
        OutputTextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        scom  % serial port
        gimbal_yaw_time
        gimbal_yaw_actual_angle
        gimbal_yaw_target_angle
        gimbal_yaw_actual_velocity
        gimbal_yaw_target_velocity
        gimbal_yaw_actual_current
        gimbal_yaw_target_current
        gimbal_pitch_time
        gimbal_pitch_actual_angle
        gimbal_pitch_target_angle
        gimbal_pitch_actual_velocity
        gimbal_pitch_target_velocity
        gimbal_pitch_actual_current
        gimbal_pitch_target_current
    end
    
    methods (Access = private)
        
        % Plot variables to axes and set labels
        function plotToAxes(~, target_axes, time_values, actual_values, target_values, actual_label, target_label)
            
            cla(target_axes,"reset");
            plot(time_values, actual_values, "r.-", "linewidth", 2, "Parent", target_axes);
            hold(target_axes, 'on');
            plot(time_values, target_values, "b.-", "linewidth", 2, "Parent", target_axes);
            
            actual_label.Text = num2str(actual_values(end));
            target_label.Text = num2str(target_values(end));
            
        end
    
        % Print message to output
        function printToOutput(app, str)
            app.OutputTextArea.Value = [app.OutputTextArea.Value; str];
        end
    end
    
    methods (Access = public)
        
        function s_rx_callback(app, ~, ~)
            
            str = fgetl(app.scom);
            p = strsplit(str, ",");
            p1 = char(p(1));
            if strlength(p1) >=3 && p1(1) == "!" % feedback data
                if p1(2) == "g" % gimbal
                    if p1(3) == "y" % yaw
                        
                        % Update data
                        app.gimbal_yaw_time(end+1) = str2double(char(p(2)));
                        app.gimbal_yaw_actual_angle(end+1) = str2double(char(p(3)));
                        app.gimbal_yaw_target_angle(end+1) = str2double(char(p(4)));
                        app.gimbal_yaw_actual_velocity(end+1) = str2double(char(p(5)));
                        app.gimbal_yaw_target_velocity(end+1) = str2double(char(p(6)));
                        app.gimbal_yaw_actual_current(end+1) = str2double(char(p(7)));
                        app.gimbal_yaw_target_current(end+1) = str2double(char(p(8)));
                        
                        % Updata images and labels
                        if app.YawButton.Value == 1
                            app.plotToAxes(app.AngleAxes, app.gimbal_yaw_time, app.gimbal_yaw_actual_angle, app.gimbal_yaw_target_angle, app.Label, app.Label_2);
                            app.plotToAxes(app.VelocityAxes, app.gimbal_yaw_time, app.gimbal_yaw_actual_velocity, app.gimbal_yaw_target_velocity, app.Label_3, app.Label_4);
                            app.plotToAxes(app.CurrentAxes, app.gimbal_yaw_time, app.gimbal_yaw_actual_current, app.gimbal_yaw_target_current, app.Label_5, app.Label_6);
                        end
                    elseif p1(3) == "p" % pitch
                        
                        % Update data
                        app.gimbal_pitch_time(end+1) = str2double(char(p(2)));
                        app.gimbal_pitch_actual_angle(end+1) = str2double(char(p(3)));
                        app.gimbal_pitch_target_angle(end+1) = str2double(char(p(4)));
                        app.gimbal_pitch_actual_velocity(end+1) = str2double(char(p(5)));
                        app.gimbal_pitch_target_velocity(end+1) = str2double(char(p(6)));
                        app.gimbal_pitch_actual_current(end+1) = str2double(char(p(7)));
                        app.gimbal_pitch_target_current(end+1) = str2double(char(p(8)));
                        
                        % Updata images and labels
                        if app.PitchButton.Value == 1
                            app.plotToAxes(app.AngleAxes, app.gimbal_pitch_time, app.gimbal_pitch_actual_angle, app.gimbal_pitch_target_angle, app.Label, app.Label_2);
                            app.plotToAxes(app.VelocityAxes, app.gimbal_pitch_time, app.gimbal_pitch_actual_velocity, app.gimbal_pitch_target_velocity, app.Label_3, app.Label_4);
                            app.plotToAxes(app.CurrentAxes, app.gimbal_pitch_time, app.gimbal_pitch_actual_current, app.gimbal_pitch_target_current, app.Label_5, app.Label_6);
                        end
                    end
                end
            else % normal data
                app.printToOutput (str);
            end
        end
        
        function sendString(app, str)
            fprintf("Sending: %s\n", str);
            fprintf(app.scom, "%s\n", str);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function Meta_Terminal_OpeningFcn(app)
            ClearDataButtonPushed(app, 0);
        end

        % Button pushed function: ClearButton
        function ClearButton_Callback(app, event)
            app.OutputTextArea.Value = [""];
        end

        % Button pushed function: ConnectButton
        function ConnectButton_Callback(app, event)
            delete(instrfind)  % close all open connection

            % Setup the serial port
            app.scom = serial("/dev/cu.SLAB_USBtoUART","BaudRate",115200);
            app.scom.BaudRate = 115200;
            app.scom.BytesAvailableFcnMode = "terminator";
            app.scom.Terminator = "CR/LF";
            app.scom.BytesAvailableFcn = @app.s_rx_callback;
            fopen(app.scom);
            
            app.printToOutput ("[Connected]");
            fprintf("[Connected]");
        end

        % Button pushed function: SendButton
        function SendButton_Callback(app, event)
            sendString(app, app.SendText.Value);
        end

        % Button pushed function: StopButton
        function StopButton_Callback(app, event)
            delete(instrfind);  % close all open connection
            app.printToOutput ("[Disconnected]");
            fprintf("[Disconnected]");
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            
            % Clear the data
            app.gimbal_yaw_time = [];
            app.gimbal_yaw_actual_angle = [];
            app.gimbal_yaw_target_angle =[];
            app.gimbal_yaw_actual_velocity = [];
            app.gimbal_yaw_target_velocity = [];
            app.gimbal_yaw_actual_current = [];
            app.gimbal_yaw_target_current = [];
            
            app.gimbal_pitch_time = [];
            app.gimbal_pitch_actual_angle = [];
            app.gimbal_pitch_target_angle =[];
            app.gimbal_pitch_actual_velocity = [];
            app.gimbal_pitch_target_velocity = [];
            app.gimbal_pitch_actual_current = [];
            app.gimbal_pitch_target_current = [];
            
            % Clear the image
            cla(app.AngleAxes, "reset");
            cla(app.VelocityAxes, "reset");
            cla(app.CurrentAxes, "reset");
        end

        % Button pushed function: statsButton
        function statsButtonPushed(app, event)
            app.sendString("stats");
        end

        % Button pushed function: threadsButton
        function threadsButtonPushed(app, event)
            app.sendString("threads");
        end

        % Button pushed function: memButton
        function memButtonPushed(app, event)
            app.sendString("mem");
        end

        % Button pushed function: systimeButton
        function systimeButtonPushed(app, event)
            app.sendString("systime");
        end

        % Button pushed function: g_enableButton
        function g_enableButtonPushed(app, event)
            app.sendString(sprintf("g_enable %d %d", app.EnableYawCheckBox.Value, app.EnablePitchCheckBox.Value));
        end

        % Button pushed function: g_fixButton
        function g_fixButtonPushed(app, event)
            app.sendString("g_fix");
        end

        % Button pushed function: g_setButton
        function g_setButtonPushed(app, event)
            app.sendString(sprintf("g_set %d %d", str2double(app.YawTargetCurrentEditField.Value), str2double(app.PitchTargetCurrentEditField.Value)));
        end

        % Close request function: MetaTerminal
        function MetaTerminalCloseRequest(app, event)
            delete(app)
            delete(instrfind);  % close all open connection
            fprintf("[Disconnect All]");
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetaTerminal = uifigure;
            app.MetaTerminal.Name = 'Meta Terminal';
            app.MetaTerminal.CloseRequestFcn = createCallbackFcn(app, @MetaTerminalCloseRequest, true);
            app.AngleAxes = uiaxes(app.MetaTerminal);
            app.VelocityAxes = uiaxes(app.MetaTerminal);
            app.CurrentAxes = uiaxes(app.MetaTerminal);
            app.ConnectButton = uibutton(app.MetaTerminal, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_Callback, true);
            app.ConnectButton.Text = 'Connnect';
            app.StopButton = uibutton(app.MetaTerminal, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Callback, true);
            app.StopButton.Text = 'Stop';
            app.SendText = uieditfield(app.MetaTerminal, 'text');
            app.SendButton = uibutton(app.MetaTerminal, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButton_Callback, true);
            app.SendButton.Text = 'Send';
            app.ClearButton = uibutton(app.MetaTerminal, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_Callback, true);
            app.ClearButton.Text = 'Clear Output';
            app.FunctionButtonGroup = uibuttongroup(app.MetaTerminal);
            app.FunctionButtonGroup.Title = 'Functions';
            app.statsButton = uibutton(app.FunctionButtonGroup, 'push');
            app.statsButton.ButtonPushedFcn = createCallbackFcn(app, @statsButtonPushed, true);
            app.statsButton.Text = 'stats';
            app.threadsButton = uibutton(app.FunctionButtonGroup, 'push');
            app.threadsButton.ButtonPushedFcn = createCallbackFcn(app, @threadsButtonPushed, true);
            app.threadsButton.Text = 'threads';
            app.memButton = uibutton(app.FunctionButtonGroup, 'push');
            app.memButton.ButtonPushedFcn = createCallbackFcn(app, @memButtonPushed, true);
            app.memButton.Text = 'mem';
            app.systimeButton = uibutton(app.FunctionButtonGroup, 'push');
            app.systimeButton.ButtonPushedFcn = createCallbackFcn(app, @systimeButtonPushed, true);
            app.systimeButton.Text = 'systime';
            app.EnableYawCheckBox = uicheckbox(app.FunctionButtonGroup);
            app.EnableYawCheckBox.Text = 'Enable Yaw';
            app.EnablePitchCheckBox = uicheckbox(app.FunctionButtonGroup);
            app.EnablePitchCheckBox.Text = 'Enable Pitch';
            app.g_enableButton = uibutton(app.FunctionButtonGroup, 'push');
            app.g_enableButton.ButtonPushedFcn = createCallbackFcn(app, @g_enableButtonPushed, true);
            app.g_enableButton.Text = 'g_enable';
            app.g_fixButton = uibutton(app.FunctionButtonGroup, 'push');
            app.g_fixButton.ButtonPushedFcn = createCallbackFcn(app, @g_fixButtonPushed, true);
            app.g_fixButton.Text = 'g_fix';
            app.YawTargetCurrentEditFieldLabel = uilabel(app.FunctionButtonGroup);
            app.YawTargetCurrentEditFieldLabel.Text = 'Yaw Target Current';
            app.YawTargetCurrentEditField = uieditfield(app.FunctionButtonGroup, 'text');
            app.YawTargetCurrentEditField.Value = '0';
            app.PitchTargetCurrentEditFieldLabel = uilabel(app.FunctionButtonGroup);
            app.PitchTargetCurrentEditFieldLabel.Text = 'Pitch Target Current';
            app.PitchTargetCurrentEditField = uieditfield(app.FunctionButtonGroup, 'text');
            app.PitchTargetCurrentEditField.Value = '0';
            app.g_setButton = uibutton(app.FunctionButtonGroup, 'push');
            app.g_setButton.ButtonPushedFcn = createCallbackFcn(app, @g_setButtonPushed, true);
            app.g_setButton.Text = 'g_set';
            app.ClearDataButton = uibutton(app.MetaTerminal, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.AngleLabel = uilabel(app.MetaTerminal);
            app.AngleLabel.Text = 'Angle';
            app.CurrentLabel = uilabel(app.MetaTerminal);
            app.CurrentLabel.Text = 'Current:';
            app.TargetLabel = uilabel(app.MetaTerminal);
            app.TargetLabel.Text = 'Target:';
            app.Label = uilabel(app.MetaTerminal);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.MetaTerminal);
            app.Label_2.Text = '0';
            app.CurrentLabel_2 = uilabel(app.MetaTerminal);
            app.CurrentLabel_2.Text = 'Current:';
            app.TargetLabel_2 = uilabel(app.MetaTerminal);
            app.TargetLabel_2.Text = 'Target:';
            app.Label_3 = uilabel(app.MetaTerminal);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.MetaTerminal);
            app.Label_4.Text = '0';
            app.VelocityLabel = uilabel(app.MetaTerminal);
            app.VelocityLabel.Text = 'Velocity';
            app.CurrentLabel_3 = uilabel(app.MetaTerminal);
            app.CurrentLabel_3.Text = 'Current:';
            app.TargetLabel_3 = uilabel(app.MetaTerminal);
            app.TargetLabel_3.Text = 'Target:';
            app.Label_5 = uilabel(app.MetaTerminal);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.MetaTerminal);
            app.Label_6.Text = '0';
            app.CurrentLabel_4 = uilabel(app.MetaTerminal);
            app.CurrentLabel_4.Text = 'Current';
            app.DisplayButtonGroup = uibuttongroup(app.MetaTerminal);
            app.DisplayButtonGroup.Title = 'Display';
            app.YawButton = uiradiobutton(app.DisplayButtonGroup);
            app.YawButton.Text = 'Yaw';
            app.YawButton.Value = true;
            app.PitchButton = uiradiobutton(app.DisplayButtonGroup);
            app.PitchButton.Text = 'Pitch';
            app.OutputTextArea = uitextarea(app.MetaTerminal);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Meta_Terminal_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MetaTerminal)

            % Execute the startup function
            runStartupFcn(app, @Meta_Terminal_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetaTerminal)
        end
    end
end